classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        ButtonGroup  matlab.ui.container.ButtonGroup
        Button       matlab.ui.control.ToggleButton
        Button_2     matlab.ui.control.ToggleButton
        Button_3     matlab.ui.control.ToggleButton
        Button_4     matlab.ui.control.ToggleButton
        Button_5     matlab.ui.control.ToggleButton
        Label        matlab.ui.control.Label
        EditField    matlab.ui.control.NumericEditField
        Label_2      matlab.ui.control.Label
        EditField_2  matlab.ui.control.EditField
        Button_6     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SliderValueChanged(app, event)
        
           
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,app.Button.Text)
                '开你妈的话'
            end
            if strcmp(selectedButton.Text,app.Button_2.Text)
                '销户'
            end
            if strcmp(selectedButton.Text,app.Button_3.Text)
                '存钱'
            end
            if strcmp(selectedButton.Text,app.Button_4.Text)
                '取钱'
            end
            if strcmp(selectedButton.Text,app.Button_5.Text)
                '转账'
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Button = uitogglebutton(app.ButtonGroup);
            app.Button.Text = '开户';
            app.Button.Value = true;
            app.Button_2 = uitogglebutton(app.ButtonGroup);
            app.Button_2.Text = '销户';
            app.Button_3 = uitogglebutton(app.ButtonGroup);
            app.Button_3.Text = '存钱';
            app.Button_4 = uitogglebutton(app.ButtonGroup);
            app.Button_4.Text = '取钱';
            app.Button_5 = uitogglebutton(app.ButtonGroup);
            app.Button_5.Text = '转账';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '身份证';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '密码';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = '确认';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end